classdef Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ApplyButton                  matlab.ui.control.Button
        LeadDBSVersionDropDown       matlab.ui.control.DropDown
        LeadDBSVersionDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        TuneStim % Description
        updatedSettings
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, TuneStim, cohort)
            app.updatedSettings = cohort.simulationSettings;
            % Store main app in property
            app.TuneStim = TuneStim;
        end

        % Value changed function: LeadDBSVersionDropDown
        function LeadDBSVersionDropDownValueChanged(app, event)
            app.updatedSettings.LeadDBSVersion = app.LeadDBSVersionDropDown.Value;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            updateSettings(app.TuneStim,app.updatedSettings)
            app.TuneStim.SettingsMenu.Enable = 'on';
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeadDBSVersionDropDownLabel = uilabel(app.UIFigure);
            app.LeadDBSVersionDropDownLabel.Text = 'Lead-DBS Version';
            app.LeadDBSVersionDropDown = uidropdown(app.UIFigure);
            app.LeadDBSVersionDropDown.Items = {'2.7', '3.2'};
            app.LeadDBSVersionDropDown.ValueChangedFcn = createCallbackFcn(app, @LeadDBSVersionDropDownValueChanged, true);
            app.LeadDBSVersionDropDown.Value = '3.2';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end